classdef pmcoloc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileLabel                     matlab.ui.control.Label
        PathEditField                 matlab.ui.control.EditField
        PathEditFieldLabel            matlab.ui.control.Label
        ResultTextArea                matlab.ui.control.TextArea
        ResultTextAreaLabel           matlab.ui.control.Label
        RandomSeedEditField           matlab.ui.control.NumericEditField
        RandomSeedLabel               matlab.ui.control.Label
        AnalysisButton                matlab.ui.control.Button
        GreenThresholdEditField       matlab.ui.control.NumericEditField
        GreenThresholdEditFieldLabel  matlab.ui.control.Label
        RedThresholdEditField         matlab.ui.control.NumericEditField
        RedThresholdEditFieldLabel    matlab.ui.control.Label
        SetROIButton                  matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        HeightYEditField              matlab.ui.control.NumericEditField
        HeightYEditFieldLabel         matlab.ui.control.Label
        StartingYEditField            matlab.ui.control.NumericEditField
        StartingYEditFieldLabel       matlab.ui.control.Label
        WidthXEditField               matlab.ui.control.NumericEditField
        WidthXLabel                   matlab.ui.control.Label
        StartingXEditField            matlab.ui.control.NumericEditField
        StartingXEditFieldLabel       matlab.ui.control.Label
        UIAxesROIRaG_2                matlab.ui.control.UIAxes
        UIAxesROIG_2                  matlab.ui.control.UIAxes
        UIAxesROIR_2                  matlab.ui.control.UIAxes
        UIAxesROIRaG                  matlab.ui.control.UIAxes
        UIAxesROIG                    matlab.ui.control.UIAxes
        UIAxesROIR                    matlab.ui.control.UIAxes
        UIAxesGCH                     matlab.ui.control.UIAxes
        UIAxesRCH                     matlab.ui.control.UIAxes
        UIAxesROI_2                   matlab.ui.control.UIAxes
        UIAxesROI                     matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        myImage % Overall
        myPlot % the plotted myImage
        myROI % Region of interest
    end
    
    methods (Access = private)
        function myImage = array2img(app,myArray)
            myArraySize = size(myArray);
            myImage = zeros(myArraySize(1),myArraySize(2),3);
            myImage(:,:,1) = myArray+0;
            myImage(:,:,2) = myArray+0;
            myImage(:,:,3) = myArray+0;
        end

        function updatehistogram(app,myImage)
            switch size(myImage,3)
                case 3
                    myImageRedCh = myImage(:,:,1);
                    myImageGreenCh = myImage(:,:,2);
                    
                    histr = histogram(app.UIAxesRCH, myImageRedCh,'NumBins', 256 , 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.UIAxesGCH, myImageGreenCh, 'NumBins', 256, 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            app.UIAxesRCH.XLim = [0,255];
            %app.UIAxesRCH.YLim = [1,max(max(histr.BinCounts))];
            app.UIAxesGCH.XLim = [0,255];
            %app.UIAxesRCH.YLim = [1,max(max(histg.BinCounts))];            
        end
        
        
        function updateimage(app,imagefile)
            try
                app.myImage = imread(imagefile);
                app.myPlot = imshow(app.myImage, 'parent', app.UIAxes,'InitialMagnification','fit'); 
                %app.UIAxes.Tag = 'allData'; %Tagged as allData for future reference

                imsize = size(app.myImage);
                axis(app.UIAxes,"auto");
                axis(app.UIAxes,[0,imsize(1),0,imsize(2)])
                axis(app.UIAxes, 'on') 
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end 
            app.StartingXEditField.Limits = [1,imsize(2)];
            app.StartingYEditField.Limits = [1,imsize(1)];
            
%             updatehistogram(app, im)
%             title(app.UIAxesRCH, 'Red Channel - Overall')
%             title(app.UIAxesGCH, 'Red Channel - Overall')
        end
        

        function updateimageROI(app)
            try
                imsize = size(app.myImage);
                
                startY = app.StartingXEditField.Value;
                endY = min(startY+app.WidthXEditField.Value-1,imsize(2));
                
                startX = app.StartingYEditField.Value;
                endX = min(startX+app.HeightYEditField.Value-1,imsize(1));
                
                %clear existing lines if they exist
                axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', 'line');
                if ~isempty(axesHandlesToChildObjects)
                    delete(axesHandlesToChildObjects);
                end
                
                line(app.UIAxes,[startY,startY], [startX,endX], 'Color',[1,1,0],'LineWidth',1)
                line(app.UIAxes,[endY,endY], [startX,endX],'Color',[1,1,0],'LineWidth',1)
                line(app.UIAxes,[startY,endY], [startX,startX],'Color',[1,1,0],'LineWidth',1)
                line(app.UIAxes,[startY,endY], [endX,endX],'Color',[1,1,0],'LineWidth',1)
                app.myROI = app.myImage(startX:endX, startY:endY, :);
                
                ROIsize = size(app.myROI);
                imshow(app.myROI, 'parent', app.UIAxesROI,'InitialMagnification','fit');
%                 axis(app.UIAxesROI,"auto");
%                 axis(app.UIAxesROI,[0,ROIsize(1),0,ROIsize(2)])
%                 axis(app.UIAxesROI, 'on') 
                
                updatehistogram(app, app.myROI)
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end 
        end
        
        function updatingsubplots(app)
            try
                RedThreshold = app.RedThresholdEditField.Value;
                GreenThreshold = app.GreenThresholdEditField.Value;
                
                myROIRedData = app.myROI(:,:,1)>RedThreshold;
                myROIRedImage = array2img(app,myROIRedData);
                imshow(myROIRedImage, 'parent', app.UIAxesROIR,'InitialMagnification','fit');
                
                myROIGreenData = app.myROI(:,:,2)>GreenThreshold;
                myROIGreenImage = array2img(app,myROIGreenData);
                imshow(myROIGreenImage, 'parent', app.UIAxesROIG,'InitialMagnification','fit');
                
                myROIGDWData = myROIRedData & myROIGreenData;
                myROIGDWImage = array2img(app,myROIGDWData);
                imshow(myROIGDWImage, 'parent', app.UIAxesROIRaG,'InitialMagnification','fit');
                
                rng(app.RandomSeedEditField.Value);
                
                myROIsize = size(app.myROI);
                myRndArray = randperm(myROIsize(1)*myROIsize(2));
                myROIGreenRnd = app.myROI;
                Temp = myROIGreenRnd(:,:,2);
                Temp = Temp(:);
                Temp = Temp(myRndArray);
                Temp = reshape(Temp,[myROIsize(1),myROIsize(2)]);
                myROIGreenRnd(:,:,2) = Temp;
                imshow(myROIGreenRnd, 'parent', app.UIAxesROI_2,'InitialMagnification','fit');
                
                imshow(myROIRedImage, 'parent', app.UIAxesROIR_2,'InitialMagnification','fit');
                
                myROIGreenRndData = myROIGreenData(:);
                myROIGreenRndData = myROIGreenRndData(myRndArray);
                myROIGreenRndData = reshape(myROIGreenRndData,[myROIsize(1),myROIsize(2)]);
                myROIGreenRndImage = array2img(app,myROIGreenRndData);
                imshow(myROIGreenRndImage, 'parent', app.UIAxesROIG_2,'InitialMagnification','fit');                
                
                myROIGDWRndData = myROIRedData & myROIGreenRndData;
                myROIGDWRndImage = array2img(app,myROIGDWRndData);
                imshow(myROIGDWRndImage, 'parent', app.UIAxesROIRaG_2,'InitialMagnification','fit');
                
                M1 = sum(sum(myROIGDWData))/sum(sum(myROIRedData));
                M2 = sum(sum(myROIGDWData))/sum(sum(myROIGreenData));                
                M1P = sum(sum(myROIGDWRndData))/sum(sum(myROIRedData));
                M2P = sum(sum(myROIGDWRndData))/sum(sum(myROIGreenData));                
                                
                
                app.ResultTextArea.Value = ['File = ', app.FileLabel.Text , newline,...
                    'starting X = ',num2str(app.StartingXEditField.Value) , newline,...
                    'Width X = ',num2str(app.WidthXEditField.Value) , newline,...
                    'starting Y = ',num2str(app.StartingYEditField.Value) , newline,...
                    'Height Y = ',num2str(app.HeightYEditField.Value) , newline,... 
                    'Red Threshold = ',num2str(app.RedThresholdEditField.Value) , newline,...
                    'Green Threshold = ',num2str(app.GreenThresholdEditField.Value) , newline,...
                    'Random Seed = ',num2str(app.RandomSeedEditField.Value),newline,newline,...
                    'For the initial region of interest',newline,...
                    'Num of pixels red value above threshold = ', num2str(sum(sum(myROIRedData))),newline,...
                    'Num of pixels green value above threshold = ', num2str(sum(sum(myROIGreenData))),newline,...
                    'Num of pixels both values above threshold = ', num2str(sum(sum(myROIGDWData))),newline,...
                    'Num(both) / Num(red) = ', num2str(round(M1,4)),newline,...
                    'Num(both) / Num(Green) = ', num2str(round(M2,4)),newline,newline,...
                    'After reshuffling the green channel of region of interest',newline,...
                    'Num of pixels red value above threshold = ', num2str(sum(sum(myROIRedData))),newline,...
                    'Num of pixels green value above threshold = ', num2str(sum(sum(myROIGreenRndData))),newline,...
                    'Num of pixels both values above threshold = ', num2str(sum(sum(myROIGDWRndData))),newline,...
                    'Num(both) / Num(red) = ', num2str(round(M1P,4)),newline,...
                    'Num(both) / Num(Green) = ', num2str(round(M2P,4)),newline,...                    
                    ];
                
                clipboard('copy',[num2str(sum(sum(myROIRedData))),char(9), ...
                    num2str(sum(sum(myROIGreenData))),char(9), ...
                    num2str(sum(sum(myROIGDWData))),char(9),...
                    num2str(sum(sum(myROIGDWRndData))),...
                    ])

            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;                
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.UIAxes.Toolbar.Visible = 'off'; 
%             disableDefaultInteractivity(app.UIAxes)
            app.UIAxesRCH.YScale = 'log';
            app.UIAxesGCH.YScale = 'log';
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            
            [f, p] = uigetfile(fullfile(app.PathEditField.Value,'*.*'));
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
               app.FileLabel.Text = fullfile(p,f);
            end
        end

        % Button pushed function: SetROIButton
        function SetROIButtonPushed(app, event)
            updateimageROI(app)
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            updatingsubplots(app)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %Create if statement that determines if the user clicked on the
            %line of the top UIAxes. If they didn't, do nothing
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject,app.myPlot)
                  %Store the CurrentPiont of the UIAxes as app.a
                  app.StartingXEditField.Value = app.UIAxes.CurrentPoint(1,1);
                  app.StartingYEditField.Value = app.UIAxes.CurrentPoint(1,2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxesROI = uiaxes(app.UIFigure);
            title(app.UIAxesROI, 'Region of Interest')
            app.UIAxesROI_2 = uiaxes(app.UIFigure);
            title(app.UIAxesROI_2, 'Green Pixels Reshuffled')
            app.UIAxesRCH = uiaxes(app.UIFigure);
            title(app.UIAxesRCH, 'ROI Red Channel')
            xlabel(app.UIAxesRCH, 'Red Channel Intensity')
            ylabel(app.UIAxesRCH, 'Count')
            app.UIAxesGCH = uiaxes(app.UIFigure);
            title(app.UIAxesGCH, 'ROI Green Channel')
            xlabel(app.UIAxesGCH, 'Green Channel Intensity')
            ylabel(app.UIAxesGCH, 'Count')
            app.UIAxesROIR = uiaxes(app.UIFigure);
            title(app.UIAxesROIR, 'Red Pixels > Threshold')
            app.UIAxesROIG = uiaxes(app.UIFigure);
            title(app.UIAxesROIG, 'Green Pixels > Threshold')
            app.UIAxesROIRaG = uiaxes(app.UIFigure);
            title(app.UIAxesROIRaG, 'Both Pixels > Threshold')
            app.UIAxesROIR_2 = uiaxes(app.UIFigure);
            title(app.UIAxesROIR_2, 'Red Ch > Threshold')
            app.UIAxesROIG_2 = uiaxes(app.UIFigure);
            title(app.UIAxesROIG_2, 'Green Ch > Threshold')
            app.UIAxesROIRaG_2 = uiaxes(app.UIFigure);
            title(app.UIAxesROIRaG_2, 'Region of Interest')
            app.StartingXEditFieldLabel = uilabel(app.UIFigure);
            app.StartingXEditFieldLabel.Text = 'Starting X';
            app.StartingXEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingXEditField.RoundFractionalValues = 'on';
            app.StartingXEditField.Value = 500;
            app.WidthXLabel = uilabel(app.UIFigure);
            app.WidthXLabel.Text = 'Width ΔX';
            app.WidthXEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthXEditField.RoundFractionalValues = 'on';
            app.WidthXEditField.Value = 100;
            app.StartingYEditFieldLabel = uilabel(app.UIFigure);
            app.StartingYEditFieldLabel.Text = 'Starting Y';
            app.StartingYEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingYEditField.RoundFractionalValues = 'on';
            app.StartingYEditField.Value = 500;
            app.HeightYEditFieldLabel = uilabel(app.UIFigure);
            app.HeightYEditFieldLabel.Text = 'Height ΔY';
            app.HeightYEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightYEditField.RoundFractionalValues = 'on';
            app.HeightYEditField.Value = 100;
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SetROIButton = uibutton(app.UIFigure, 'push');
            app.SetROIButton.ButtonPushedFcn = createCallbackFcn(app, @SetROIButtonPushed, true);
            app.SetROIButton.Text = 'Set ROI';
            app.RedThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.RedThresholdEditFieldLabel.Text = 'Red Threshold';
            app.RedThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedThresholdEditField.Value = 100;
            app.GreenThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.GreenThresholdEditFieldLabel.Text = 'Green Threshold';
            app.GreenThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenThresholdEditField.Value = 100;
            app.AnalysisButton = uibutton(app.UIFigure, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.RandomSeedLabel = uilabel(app.UIFigure);
            app.RandomSeedLabel.Text = 'Random Seed';
            app.RandomSeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.RandomSeedEditField.RoundFractionalValues = 'on';
            app.RandomSeedEditField.ValueDisplayFormat = '%11.9g';
            app.RandomSeedEditField.Value = 114514;
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.Value = '/path_to_folder/';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = 'File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pmcoloc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end